classdef SolarSpecXPSapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        XPSFittingUIFigure             matlab.ui.Figure
        ConfigPanel                    matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Percent                        matlab.ui.control.NumericEditField
        FileloadedEditField            matlab.ui.control.EditField
        FileloadedLabel                matlab.ui.control.Label
        SetAxesLimitsButton            matlab.ui.control.Button
        AxesTable                      matlab.ui.control.Table
        FitResultsAxesLimitsLabel      matlab.ui.control.Label
        UniquenessStepEditField        matlab.ui.control.NumericEditField
        StepeVEditFieldLabel           matlab.ui.control.Label
        UniquenessMaxEditField         matlab.ui.control.NumericEditField
        MaxeVEditFieldLabel            matlab.ui.control.Label
        UniquenessMinEditField         matlab.ui.control.NumericEditField
        MineVEditFieldLabel            matlab.ui.control.Label
        UniquenessPlotButton           matlab.ui.control.Button
        GaussianMixEditField           matlab.ui.control.NumericEditField
        Guaussianmix0100GaussianLabel  matlab.ui.control.Label
        ApplyShiftButton               matlab.ui.control.Button
        FWHMEditField                  matlab.ui.control.NumericEditField
        FWHMeVLabel                    matlab.ui.control.Label
        BEshiftEditField               matlab.ui.control.NumericEditField
        BEshifteVEditFieldLabel        matlab.ui.control.Label
        ButtonPanel                    matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        LoadDataButton                 matlab.ui.control.Button
        FitReuseButton                 matlab.ui.control.Button
        PerformFitButton               matlab.ui.control.Button
        SelectInitialPeaksButton       matlab.ui.control.Button
        BkgGrid                        matlab.ui.container.GridLayout
        LinearBGButton                 matlab.ui.control.Button
        ShirleyBGButton                matlab.ui.control.Button
        FitRangeGrid                   matlab.ui.container.GridLayout
        SetFitRangeKBDButton           matlab.ui.control.Button
        SetFitRangeMouseButton         matlab.ui.control.Button
        ShiftresidualsEditField        matlab.ui.control.NumericEditField
        ShiftresidualsEditFieldLabel   matlab.ui.control.Label
        LinBGToleranceEditField        matlab.ui.control.NumericEditField
        ToleranceoninitiallinBGEditFieldLabel  matlab.ui.control.Label
        LinslopemaxEditField           matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        LinslopeminEditField           matlab.ui.control.NumericEditField
        LinslopeminEditFieldLabel      matlab.ui.control.Label
        ShiftResidualsButton           matlab.ui.control.Button
        ExportFitResultsButton         matlab.ui.control.Button
        ginputLabel                    matlab.ui.control.Label
        UniquenessAxes                 matlab.ui.control.UIAxes
        FitResultAxes                  matlab.ui.control.UIAxes
        PeakPositionAxes               matlab.ui.control.UIAxes
        BGAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        FullFileName        % File loaded
        BE                  % Binding energy data
        Intensity           % Intensity data
        Background          % Value of the Shirley background
        LinBackground       % Value of the linear background
        BSintensity         % Background corrected intensities
        LowShirleyBound     % Low BE point for Shirley BG
        HighShirleyBound    % High BE point for Shirley BG
        XPeakGuess          % X coordinate of initial peak guess
        YPeakGuess          % Y coordinate of initial peak guess
        NumPeaks            % Number of peaks to fit
        FitBE               % BE vector used for fitting
        BEshift             % Keep tracks of the total BE shift applied

        LinearBGROI         % Line ROI created from drawline
        LowLinearBound      % Low BE point for linear BG
        HighLinearBound     % High BE point for linear BG
        InitialBackground   % Handle to Shirley or linear background for plotting
        LinearBGslope       % Value of linear BG slope determined by user
        LinearBGoffset      % Value of linear BG offset determined by user

        minFitBound         % Lower fitting bound
        LowerFitBoundLine   % Line to display lower fitting bound
        maxFitBound         % Upper fitting bound
        UpperFitBoundLine   % Line to display upper fitting bound

        InitialGuessPoints  % Points to display initial peak guesses

        FitResidualsData    % Line that shows the residuals
        FitResidualsRef     % Solid line to show 0 reference

        % Holds all temporary data necessary for altering
        tempMinFit % Min fit bound
        tempMaxFit % Max fit bound
        tempSBkg % Shirley background
        tempLBkg % Lin background
        tempXPeaks % XData values of peaks
        tempYPeaks % YData values of peaks

        Size % Holds size of ShirleyIndex for preallocating
        PeakBool = 0 % Holds boolean for keypress during initial peaks
        roi % Holds ROI points for initial peaks
    end

    properties (Access = public)
        PeakLine % Holds PeakAxes line object
    end

    methods (Access = private)

        function funcHandle = GLSfunc(~)
            %c(1) is the peak intensity, c(2) is the mixing ratio, c(3) is
            %the peak BE, c(4) is the FWHM in eV. From https://doi.org/10.1016/j.apsusc.2018.03.190
            GLSfun = @(x,c) c(1)*(1-c(2))*exp(-4*log(2)*((x-c(3)).^2)./(c(4))^2)+c(1)*c(2)./(1+4*((x-c(3)).^2)./(c(4)).^2);
            funcHandle = GLSfun;
        end

        function [output] = SingleVoigtPeak(~,x,params)
            %SingleVoigtPeak Single Voigt peak as sum of Gauss and Lorentzian
            % x is a column vector; params is a 1 by 4 row vector
            output = params(1)*(1-params(2))*exp(-4*log(2)*((x-params(3)).^2)./(params(4))^2)+...
                params(1)*params(2)./(1+4*((x-params(3)).^2)./(params(4)).^2);
        end

        function [output] = MultiVoigtPeak(app,params,x)
            %MultiPeakFunc Function to evaluate multiple Voigt peaks as defined by SinglePeakFunc
            % x is a column vector; params is an n+1 by 4 matrix, where n is the number of peaks
            % The last row of params is for the linear background (first 2 elements)
            %params(n,1) is the peak intensity, params(n,2) is the mixing ratio, params(n,3) is
            %the peak BE, params(n,4) is the FWHM in eV. From https://doi.org/10.1016/j.apsusc.2018.03.190

            output = zeros(length(x),1);
            TempOutput = zeros(length(x),1);

            for i = 1 : size(params,1)
                TempOutput = SingleVoigtPeak(app,x,params(i,:));     % Sum components of all Voigt peaks
                output = output + TempOutput;
            end

            output = output + params(end,1) + params(end,2)*(x-min(x));  % Add linear background. 0 contribution from slope at low BE.
        end

        function base = shirley_calculate(app,x,y)
            tol = 1e-5;
            maxit = 100;

            % Make sure we've been passed arrays and not lists.
            % Sanity check: Do we actually have data to process here?
            if ~(any(x) && any(y))
                disp("One of the arrays x or y is empty. Returning zero background.");
                base = x * 0;
                return
            end

            % Locate the biggest peak.
            [~,maxidx] = min(abs(y - max(y)));

            % It's possible that maxidx will be 0 or -1. If that is the case,
            % we can't use this algorithm, we return a zero background.
            if maxidx == 0 || maxidx >= length(y) - 1
                disp("Boundaries too high for algorithm: returning a zero background.");
                base = x * 0;
                return
            end

            % Next ensure the energy values are *decreasing* in the array,
            % if not, reverse them.
            if x(1) < x(end)
                is_reversed = true;

                x = flip(x);
                y = flip(y);
            else
                is_reversed = false;
            end

            % # Locate the minima either side of maxidx.
            [~,lmidx] = min(abs(y(1:maxidx-1) - min(y(1:maxidx-1))));
            [~,rmidx] = min(abs(y(maxidx:end) - min(y(maxidx:end))));
            rmidx = rmidx + maxidx - 1;

            % Find necessary x and y values of minima on each side
            xl = x(lmidx);

            % Instead use boxcar average if lmidx is large enough
            if lmidx > 3
                yl = mean(y(lmidx-2:lmidx+2));
            else
                yl = y(lmidx);
            end

            % xr = x(rmidx);
            yr = y(rmidx);

            bases = [];

            % If found minima on left side is greater than local minima between major peaks, partition
            while y(1) > yl && (x(1) - xl) > 2 % Recursive implementation here

                [x1,y1] = fit_range(app,x,y,x(end),xl);
                base1 = shirley_calculate(app,x1,y1);

                bases = [bases;flip(base1)];

                [x2,y2] = fit_range(app,x,y,xl,x(1));
                base2 = shirley_calculate(app,x2,y2);

                bases = [bases;flip(base2)];

                bases = flip(bases);
                bases(end) = [];

                base = bases;
                return
            end

            % Max integration index
            imax = rmidx - 1;

            % Initial value of the background shape B. The total background S = yr + B,
            % and B is equal to (yl - yr) below lmidx and initially zero above.
            B = y * 0;
            B(1:lmidx) = yl - yr;

            Bnew = B;

            it = 0;
            while it < maxit
                % Calculate new k = (yl - yr) / (int_(xl)^(xr) J(x') - yr - B(x') dx')
                ksum = 0.0;
                for i = lmidx:imax-1 %range(lmidx, imax)
                    ksum = ksum + ((x(i) - x(i+1)) * 0.5 * (y(i) + y(i+1) - 2*yr - B(i) - B(i+1)));
                end
                k = (yl - yr) / ksum;
                % Calculate new B
                for i = lmidx:rmidx-1 %range(lmidx, rmidx)
                    ysum = 0.0;
                    for j = i:imax-1 %range(i, imax)
                        ysum = ysum + ((x(j) - x(j+1)) * 0.5 * (y(j) + y(j+1) - 2*yr - B(j) - B(j+1)));
                    end
                    Bnew(i) = k * ysum;
                end
                % If Bnew is close to B, exit.
                % if norm(Bnew - B) < tol:
                B = Bnew - B;

                if sum(B.^2) < tol^2
                    B = Bnew;
                    break
                else
                    B = Bnew;
                end
                it = it + 1;
            end

            if it >= maxit
                disp("Max iterations exceeded before convergence.");
            end

            if is_reversed
                disp("Shirley BG: tol (ini = " + tol + ") , iteration (max = " + maxit + "): " + it);
                base = flip(yr + B);
                return
            else
                disp("Shirley BG: tol (ini = " + tol + ") , iteration (max = " + maxit + "): " + it);
                base = yr + B;
                return
            end
        end

        function [coefficients,resnorm] = fitting(app,BE,Intensity,fwhm,type)
            if type == "Unique"
                FWHM = fwhm;
            else
                FWHM = app.FWHMEditField.Value;
            end

            numP = app.NumPeaks;

            %Estimate constant background from intensity at low BE
            InitialGuess(numP+1,1) = Intensity(end);  % Estimate constant background from point at loq BE

            if isempty(app.LinearBGoffset) == 1 %Shirley BG used
%                 LowerBound(numP+1,1) = -inf;
%                 UpperBound(numP+1,1) = inf;
                LowerBound(numP+1,1) = 0;
                UpperBound(numP+1,1) = 0;
            else                                %Linear BG
                LowerBound(numP+1,1) = app.LinearBGoffset*(1-app.LinBGToleranceEditField.Value/100);
                UpperBound(numP+1,1) = app.LinearBGoffset*(1+app.LinBGToleranceEditField.Value/100);
            end

            %Estimate linear background slope from first and last points
            InitialGuess(numP+1,2) = (Intensity(end) - Intensity(1))/length(Intensity);
            if isempty(app.LinearBGslope) == 1  %Shirley BG used
%                 LowerBound(numP+1,2) = -inf;
%                 UpperBound(numP+1,2) = inf;
                LowerBound(numP+1,2) = 0;
                UpperBound(numP+1,2) = 0;
            else                                %Linear BG
                LowerBound(numP+1,2) = app.LinearBGslope*(1-app.LinBGToleranceEditField.Value/100);
                UpperBound(numP+1,2) = app.LinearBGslope*(1+app.LinBGToleranceEditField.Value/100);
            end

            %Fill out InitialGuess and Bound matrices
            InitialGuess(numP+1,3:4) = 0;
            LowerBound(numP+1,3:4) = 0;
            UpperBound(numP+1,3:4) = 0;

            %% Construct initial guesses from selected peaks
            for i = 1:1:numP
                InitialGuess(i,1) = app.YPeakGuess(i);
                InitialGuess(i,3) = app.XPeakGuess(i);
            end

            InitialGuess(1:numP,2) = app.GaussianMixEditField.Value;
            InitialGuess(1:numP,4) = FWHM;

            LowerBound(1:numP,1) = 0;
            LowerBound(1:numP,2) = app.GaussianMixEditField.Value;
            LowerBound(1:numP,3) = app.minFitBound;
            LowerBound(1:numP,4) = FWHM*(1-(app.Percent.Value/100));

            UpperBound(1:numP,1) = max(app.Intensity); % SHOULD THIS BE app.Intensity or Intensity
            %             testUpper(1:numP,1) = max(Intensity)
            UpperBound(1:numP,2) = app.GaussianMixEditField.Value;
            UpperBound(1:numP,3) = app.maxFitBound;
            UpperBound(1:numP,4) = FWHM*(1+(app.Percent.Value/100));

            %% Perform the fitting
            % Set fitting options
            options = optimoptions("lsqcurvefit","MaxFunctionEvaluations",1000*numP);

            % Now the next line is where the actual model computation is done.
            [coefficients,resnorm] = lsqcurvefit(@(x,params) app.MultiVoigtPeak(x,params),...
                InitialGuess,BE,Intensity,LowerBound,UpperBound, options);
        end

        function saveVars(app)
            app.tempMinFit = app.minFitBound;
            app.tempMaxFit = app.maxFitBound;
            app.tempSBkg = app.Background;
            app.tempLBkg = app.LinBackground;
            app.tempXPeaks = app.XPeakGuess;
            app.tempYPeaks = app.YPeakGuess;

        end

        function enableDisable(app)
            app.SetFitRangeKBDButton.Enable = "off";
            app.SetFitRangeMouseButton.Enable = "off";
            app.ShirleyBGButton.Enable = "off";
            app.LinearBGButton.Enable = "off";
            app.SelectInitialPeaksButton.Enable = "off";
            app.PerformFitButton.Enable = "off";
            app.UniquenessPlotButton.Enable = "off";
            app.FitReuseButton.Enable = "off";
            app.ApplyShiftButton.Enable = "off";
        end

        function [xn,yn] = fit_range(~,x, y, xmin, xmax)
            x_len = length(x);
            %disp(xmin, xmax)
            if xmin > xmax
                xmin0 = xmin;
                xmin = xmax;
                xmax = xmin0;
            end

            if x(1) < x(end)
                %XAS in photon energy scale or XPS in kinetic energy scale
                if x(1) < xmin || xmax < x(x_len)
                    if xmax < x(x_len)
                        for i = x_len:-1:-1
                            if x(i) <= xmax
                                rmidx = i;
                                break
                            end
                        end
                    else
                        rmidx = x_len;
                    end

                    if x(1) < xmin
                        for i = 1:x_len
                            if x(i) >= xmin
                                lmidx = i;
                                break
                            end
                        end

                    else
                        lmidx = 1;
                    end
                    xn = x(lmidx:rmidx+1);
                    yn = y(lmidx:rmidx+1);
                    %disp(len(x), len(xn), xn[0], xn[len(xn)-1])

                else
                    xn = x;
                    yn = y;
                end
            else
                % XPS in binding energy scale
                if x(x_len) < xmin || xmax < x(1)
                    if xmax < x(1)
                        for i = 1:x_len
                            if x(i) <= xmax
                                lmidx = i;
                                break
                            end
                        end

                    else
                        lmidx = 1;
                    end

                    if x(x_len) < xmin
                        for i = x_len:-1:-1
                            if x(i) >= xmin
                                rmidx = i;
                                break
                            end
                        end
                    else
                        rmidx = x_len;
                    end

                    xn = x(lmidx:rmidx);
                    yn = y(lmidx:rmidx);
                    %disp(len(x), len(xn), xn[0], xn[len(xn)-1])
                else
                    xn = x;
                    yn = y;
                end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AxesTable.Data = {280,300; 0, 1000};
            app.AxesTable.RowName={'Xaxes'; 'Yaxes'};

            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %% Select the data file to analyze
            [file,path] = uigetfile('.ascii');

            if file == 0
                return
            end

            enableDisable(app);

            app.FullFileName = [path, file];
            app.FileloadedEditField.Value = file;

            %% Setup the Import Options and import the data
            opts = delimitedTextImportOptions("NumVariables", 4);
            opts.Delimiter = "\t";
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.VariableTypes = ["string", "double", "double", "string"];

            %% Import the data and select the key ones
            FullTable = readtable(app.FullFileName,opts);

            app.BE = FullTable.Var2(5:end-2);
            app.Intensity = FullTable.Var3(5:end-2);

            % Clear temporary variables
            clear FullTable opts

            %% Reset BEshift value
            %             app.BE = app.BE + app.BEshiftEditField.Value;
            app.BEshiftEditField.Value = 0;

            % Reset BE shift tracker
            app.BEshift = app.BEshiftEditField.Value;

            %% Hide/clear plots until appropriate to work on them
            app.PeakPositionAxes.Visible = 'off';
            app.UniquenessAxes.Visible = 'off';
            app.FitResultAxes.Visible = 'off';

            delete(app.PeakPositionAxes.Children);
            delete(app.UniquenessAxes.Children);
            delete(app.FitResultAxes.Children);

            % Clear properties
            delete(app.InitialGuessPoints);
            app.LinearBGoffset = [];
            app.LinearBGslope = [];

            app.XPeakGuess = [];
            app.YPeakGuess = [];

            %% Plot data
            cla(app.BGAxes);
            minX = min(app.BE);
            maxX = max(app.BE);
            line(app.BGAxes, app.BE, app.Intensity);
            app.BGAxes.XLim = [minX, maxX];

            app.SetFitRangeKBDButton.Enable = "on";
            app.SetFitRangeMouseButton.Enable = "on";
            app.FitReuseButton.Enable = "on";
            app.ApplyShiftButton.Enable = "on";
        end

        % Button pushed function: SetFitRangeMouseButton
        function SetFitRangeMouseButtonPushed(app, event)
            %             % Check Matlab version, throw error if prior to r2020b
            %             assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.XPSFittingUIFigure.HandleVisibility;        % Current status
            app.XPSFittingUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.XPSFittingUIFigure)       % Make fig current

            app.ginputLabel.Text = 'You are now selecting the fitting range.';
            app.ginputLabel.Visible = 'on';             % Give info to user
            [FitBoundX,~,~] = ginput(2);                % Run ginput
            app.ginputLabel.Visible = 'off';

            app.XPSFittingUIFigure.HandleVisibility = fhv;        % Return original state

            % Extract bounds
            app.minFitBound = min(FitBoundX);
            app.maxFitBound = max(FitBoundX);

            % Delete old lines if there
            if isempty(app.LowerFitBoundLine)
                ...
            else
                delete(app.LowerFitBoundLine);
                delete(app.UpperFitBoundLine);
            end

            % Display bounds on plot
            app.LowerFitBoundLine = xline(app.BGAxes,app.minFitBound);
            app.UpperFitBoundLine = xline(app.BGAxes,app.maxFitBound);


            % Delete old initial guess points if there
            if isempty(app.InitialGuessPoints)
                ...
            else
                delete(app.InitialGuessPoints)
            end

            app.LinearBGButton.Enable = "on";
            app.ShirleyBGButton.Enable = "on";
        end

        % Button pushed function: SetFitRangeKBDButton
        function SetFitRangeKBDButtonPushed(app, event)
            FitBoundKBD = inputdlg({'What is the lower fitting bound in eV? : ',...
                'What is the upper fitting bound in eV? : '}, 'Choose the fitting bounds'); %'Position',[840,472,425,325]

            if isempty(FitBoundKBD) || isempty(FitBoundKBD{1,1}) || isempty(FitBoundKBD{2,1})
                disp("Please choose both fitting bounds");
                return
            end

            % Extract bounds
            FitBoundKBD = str2double(FitBoundKBD);
            app.minFitBound = min(FitBoundKBD);
            app.maxFitBound = max(FitBoundKBD);

            % Delete old lines if there
            if isempty(app.LowerFitBoundLine)
                ...
            else
                delete(app.LowerFitBoundLine);
                delete(app.UpperFitBoundLine);
            end

            % Delete old initial guess points if there
            if isempty(app.InitialGuessPoints)
                ...
            else
                delete(app.InitialGuessPoints)
            end

            % Display bounds on plot
            app.LowerFitBoundLine = xline(app.BGAxes,app.minFitBound);
            app.UpperFitBoundLine = xline(app.BGAxes,app.maxFitBound);

            app.LinearBGButton.Enable = "on";
            app.ShirleyBGButton.Enable = "on";
        end

        % Button pushed function: ShirleyBGButton
        function ShirleyBGButtonPushed(app, event)
            % Clear linear background if it is populated
            app.LinBackground = [];

            % Figure out constraints/limits
            app.LowShirleyBound = app.minFitBound; %min(app.minFitBound);
            app.HighShirleyBound = app.maxFitBound; % max(app.maxFitBound);
            [ShirleyIndex,~] = find(app.BE(:,1) >= app.LowShirleyBound & app.BE(:,1) <= app.HighShirleyBound);

            % Change inputted x and y ranges to then pass to Shirley function
            app.Size = length(ShirleyIndex);
            [xn,yn] = fit_range(app,app.BE,app.Intensity,app.LowShirleyBound,app.HighShirleyBound);
            app.Background = shirley_calculate(app,xn,yn);

            % Determine background corrected intensities
            app.BSintensity = app.Intensity(ShirleyIndex) - app.Background;
            %             app.BSintensity = app.Intensity - app.Background;

            % Clear linear background info for fitting.
            app.LinearBGslope = [];
            app.LinearBGoffset = [];

            % Delete old background line if there
            if isempty(app.InitialBackground)
                ...
            else
                delete(app.InitialBackground)
            end

            app.InitialBackground = line(app.BGAxes,app.BE(ShirleyIndex),app.Background(:,1),'Color',[0.9290 0.6940 0.1250]);

            % Make next plot visible.
            app.PeakPositionAxes.Visible = 'on';
            app.PeakLine = plot(app.PeakPositionAxes, app.BE, app.Intensity);

            if isempty(app.XPeakGuess) == 1
                ...
            else
                app.InitialGuessPoints = line(app.PeakPositionAxes,app.XPeakGuess,app.YPeakGuess,"LineStyle","none","Marker",'o',"MarkerEdgeColor",'r',"MarkerFaceColor",'r');
            end
            app.SelectInitialPeaksButton.Enable = "on";

            %             % Set up figure handle visibility, run ginput, and return state
            %             fhv = app.XPSFittingUIFigure.HandleVisibility;        % Current status
            %             app.XPSFittingUIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            %             set(0, 'CurrentFigure', app.XPSFittingUIFigure)       % Make fig current
            %
            %             app.ginputLabel.Text = 'You are now selecting the Shirley BG points. Press ESC twice to exit.';
            %             app.ginputLabel.Visible = 'on';             % Give info to user
            %             [BoundX,~,button] = ginput(2);              % run ginput
            %             app.ginputLabel.Visible = 'off';             % Hide message
            %
            %             app.XPSFittingUIFigure.HandleVisibility = fhv;        % return original state
            %
            %             %% Determine Shirley background
            %
            %             if isempty(button) == 0
            %                 global BEShirley IntensityShirley BackgroundShirley
            %                 disp('Starting Shirley background fit...')
            %

            %
            %                 BEShirley = app.BE(ShirleyIndex);
            %                 IntensityShirley = app.Intensity(ShirleyIndex);
            %
            %                 a0 = IntensityShirley(BEShirley==max(BEShirley));   %Find initial background value at highest BE
            %                 b0 = IntensityShirley(BEShirley==min(BEShirley));   %Find initial background value at lowest BE
            %
            %                 BackgroundShirley = repmat(IntensityShirley(BEShirley==min(BEShirley)),length(BEShirley),1);  %Get initial background from constant value
            %
            %                 % Determine Shirley background by minimizing the peak areas.
            %                 [BestFitValues,exitflag,output] = fminsearch(@ShirleyRG,[a0 b0]);
            %
            %                 app.Background = [repmat(BackgroundShirley(1),ShirleyIndex(1)-1,1);...
            %                     BackgroundShirley; repmat(BackgroundShirley(end),length(app.BE) - ShirleyIndex(end),1)];
            %
            %
            %
            %
            %                 % Add background line to plot
            %
            %                 hold(app.BGAxes,'on');
            %                 app.InitialBackground = plot(app.BGAxes,app.BE,app.Background);
            %                 hold(app.BGAxes,'off');
            %
            %
            %             else
            %                 ...
            %             end
        end

        % Button pushed function: LinearBGButton
        function LinearBGButtonPushed(app, event)
            % `Clear` Shirley BG values
            app.Background = zeros(size(app.Intensity));
            app.BSintensity = app.Intensity - app.Background;   % Determine Shirley background corrected intensities

            app.BSintensity = app.BSintensity(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);

            app.LinearBGROI = drawline(app.BGAxes);

            LinearBGfit = polyfit(app.LinearBGROI.Position(:,1)-app.minFitBound,app.LinearBGROI.Position(:,2),1);

            app.LinearBGslope = LinearBGfit(1);
            app.LinearBGoffset = LinearBGfit(2);
            app.LinBackground = polyval(LinearBGfit,app.BE-app.minFitBound);

            % Store ROI info for export
            app.LowLinearBound = min(app.LinearBGROI.Position(:,1));
            app.HighLinearBound = max(app.LinearBGROI.Position(:,1));

            delete(app.LinearBGROI)     % Remove ROI line

            % Delete old background line if there
            if isempty(app.InitialBackground)
                ...
            else
                delete(app.InitialBackground)
            end

            % Add background line to plot
            app.InitialBackground = line(app.BGAxes,app.BE,app.LinBackground,'Color',[0.9290 0.6940 0.1250]);

            % Make next plot visible.
            app.PeakPositionAxes.Visible = 'on';
            app.PeakLine = plot(app.PeakPositionAxes, app.BE, app.Intensity);

            if isempty(app.XPeakGuess) == 1
                ...
            else
                app.InitialGuessPoints = line(app.PeakPositionAxes,app.XPeakGuess,app.YPeakGuess,"LineStyle","none","Marker",'o',"MarkerEdgeColor",'r',"MarkerFaceColor",'r');
            end

            app.SelectInitialPeaksButton.Enable = "on";
        end

        % Button pushed function: SelectInitialPeaksButton
        function SelectInitialPeaksButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.XPSFittingUIFigure.HandleVisibility;            % Current status
            app.XPSFittingUIFigure.HandleVisibility = 'callback';     % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.XPSFittingUIFigure)           % Make fig current

            app.ginputLabel.Text = 'You are now selecting your initial guesses for the peaks. Press esc. to finish';
            app.ginputLabel.Visible = 'on';                 % Give info to user

            %             [app.XPeakGuess,app.YPeakGuess,PeakButton] = ginput(8);    % Run ginput


            app.PeakBool = 1;
            delete(app.roi)
            app.roi = [];

            while true
                if app.PeakBool == 0
                    break
                end
                
                app.roi = [app.roi;drawpoint(app.PeakPositionAxes)];
            end

            %Get rid of placeholder and last empty point
            app.roi(end) = [];
            app.NumPeaks = length(app.roi);

            for i = 1:app.NumPeaks
                app.XPeakGuess(1,i) = app.roi(i,1).Position(1);
                app.YPeakGuess(1,i) = app.roi(i,1).Position(2);
            end

            app.ginputLabel.Visible = 'off';                % Hide message

            %             app.XPeakGuess = app.XPeakGuess(PeakButton==1); % Only get coordinates when mouse was clicked
            %             app.YPeakGuess = app.YPeakGuess(PeakButton==1);
            %             app.NumPeaks = length(app.XPeakGuess);

            app.XPSFittingUIFigure.HandleVisibility = fhv;            % Return original state

            % Delete old points gobject if there are
            if isempty(app.InitialGuessPoints)
                ...
            else
                delete(app.InitialGuessPoints)
            end

            % Add peak guesses to plot
            app.InitialGuessPoints = line(app.PeakPositionAxes,app.XPeakGuess,app.YPeakGuess,"LineStyle","none","Marker",'o',"MarkerEdgeColor",'r',"MarkerFaceColor",'r');

            % Make next plots visible
            app.PerformFitButton.Enable = "on";
            app.UniquenessPlotButton.Enable = "on";
            app.UniquenessAxes.Visible = 'on';
            app.FitResultAxes.Visible = 'on';
        end

        % Button pushed function: PerformFitButton
        function PerformFitButtonPushed(app, event)
            cla(app.FitResultAxes);

            app.ginputLabel.Text = 'Fitting...';
            app.ginputLabel.Visible = 'on';             % Show message
            pause(1E-5)                                 % Needed to update message

            %Restrict data to fitting range
            app.FitBE = app.BE(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);
            %             FitIntensity = app.BSintensity(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);
            FitIntensity = app.BSintensity;

            [coefficients,~] = fitting(app,app.FitBE,FitIntensity,[],"Peak");
            %             [coefficients,~] = RunPeakFitting(app);

            %% Look at the fit results
            % Evaluate the fit and plot the comparison to the input data
            %             FitRG = MultiVoigtPeak(app, coefficients, app.FitBE) + app.Background(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);

            if isempty(app.LinBackground)
                ...
            else
                app.Background = app.Background(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);
            end

            FitRG = MultiVoigtPeak(app,coefficients,app.FitBE) + app.Background;

            BkgMdl = @(b,x) b(1) + b(2) * (x-app.minFitBound);
            %             FitBkg = BkgMdl(coefficients(app.NumPeaks+1,1:2),app.FitBE) + app.Background(app.BE<app.maxFitBound & app.BE>app.minFitBound);
            FitBkg = BkgMdl(coefficients(app.NumPeaks+1,1:2),app.FitBE) + app.Background;

            line(app.FitResultAxes, app.FitBE, FitRG,'LineStyle',':','Color',[0.15,0.15,0.15],'Linewidth',2);
            line(app.FitResultAxes, app.BE, app.Intensity,'Color',[0.64,0.08,0.18],'Linewidth',1.5);
            line(app.FitResultAxes, app.FitBE, FitBkg, 'Color',[0.50,0.50,0.50],'Linewidth',0.8)

            hold(app.FitResultAxes,"on") % Hold for fill function

            % Paramters for fill func below
            flp1 = fliplr(app.FitBE');
            flp2 = fliplr(FitBkg');

            PeakArea = zeros(app.NumPeaks);

            % Get individual peaks
            for i = 1:1:app.NumPeaks
                GaussFit = SingleVoigtPeak(app,app.FitBE,coefficients(i,:));     % Calculate fit curve
                PeakArea(i) = trapz(GaussFit);                              % Determine area of peak
                GaussFit = GaussFit + FitBkg;                               % Add background for plotting

                switch i % Change to consistent colors
                    case 1
                        colour = [0.47,0.67,0.19];
                    case 2
                        colour = [0.49,0.18,0.56];
                    case 3
                        colour = [0.30,0.75,0.93];
                    case 4
                        colour = [0.64,0.08,0.18];
                    case 5
                        colour = [0.93,0.70,0.10];
                end

                fill(app.FitResultAxes, [app.FitBE' flp1],[GaussFit' flp2],colour,'EdgeColor','none','FaceAlpha',0.5);
            end

            hold(app.FitResultAxes,"off")

            % Get relative areas
            RelArea = PeakArea./sum(PeakArea)*100;

            % Plot residuals
            FitResiduals = FitRG - app.Intensity(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);
            app.FitResidualsData = line(app.FitResultAxes, app.FitBE,FitResiduals,'LineStyle','-','Color',[0.2 0.2 0.2]);
            app.FitResidualsRef = line(app.FitResultAxes, app.FitBE,zeros(length(FitResiduals),1),'LineStyle','--','Color',[0.2 0.2 0.2]);

            % Generate legend
            LegendText{1} = 'TotalFit';
            LegendText{2} = 'Data';
            LegendText{3} = 'Background';

            for i = 1:1:app.NumPeaks
                LegendText{3+i} = convertCharsToStrings(['Peak ' num2str(i) ' : ' ...
                    num2str(coefficients(i,3),'%.1f') ' eV; ' num2str(RelArea(i),'%.0f') '%']);
            end

            LegendText{end+1} = 'Residuals';

            legend(app.FitResultAxes,LegendText,'Location','northwest','FontSize',10,'FontWeight',"bold")
            legend(app.FitResultAxes,'boxoff')

            app.ginputLabel.Visible = 'off';            % Hide message
            app.FitResultAxes.Visible = 'on';          % Show plot if there were no errors
            app.ExportFitResultsButton.Enable = "on";

            saveVars(app);
        end

        % Button pushed function: FitReuseButton
        function FitReuseButtonPushed(app, event)
            app.ginputLabel.Text = 'Reseting Properties...';
            app.ginputLabel.Visible = 'on';             % Show message
            pause(1E-5)                                 % Needed to update message

            app.minFitBound = app.tempMinFit;
            app.maxFitBound = app.tempMaxFit;
            app.Background = app.tempSBkg;
            app.LinBackground = app.tempLBkg;
            app.XPeakGuess = app.tempXPeaks;
            app.YPeakGuess = app.tempYPeaks;

            cla(app.PeakPositionAxes);
            cla(app.UniquenessAxes);
            cla(app.FitResultAxes);

            % Choose proper background method
            if isempty(app.LinBackground)   % Shirley Background
                ShirleyBGButtonPushed(app);
            else                            % Linear Background
                LinearBGButtonPushed(app);
            end

            % Redraw objects
            % Display bounds on plot
            app.LowerFitBoundLine = xline(app.BGAxes,app.minFitBound);
            app.UpperFitBoundLine = xline(app.BGAxes,app.maxFitBound);

            % Display BG data on peak position axes
            line(app.PeakPositionAxes, app.BE, app.Intensity);

            %% Make all plots visible
            app.BGAxes.Visible = 'on';
            app.PeakPositionAxes.Visible = 'on';
            app.UniquenessAxes.Visible = 'on';
            app.FitResultAxes.Visible = 'on';

            %% Run Uniqueness analysis.
            app.ginputLabel.Text = 'Running uniqueness analysis...';

            pause(1E-5)                                 % Needed to update message
            UniquenessPlotButtonPushed(app)

            %% Run Fit routine
            app.ginputLabel.Text = 'Doing final fitting...';
            pause(1E-5)                                 % Needed to update message
            PerformFitButtonPushed(app)

            app.ginputLabel.Visible = 'off';             % Hide message
            app.ShirleyBGButton.Enable = "on";
            app.LinearBGButton.Enable = "on";
            app.UniquenessPlotButton.Enable = "on";
        end

        % Button pushed function: UniquenessPlotButton
        function UniquenessPlotButtonPushed(app, event)
            cla(app.UniquenessAxes);

            app.ginputLabel.Text = 'Running uniqueness analysis...';
            app.ginputLabel.Visible = 'on';             % Show message
            pause(1E-5)                                 % Needed to update message

            index = 0;

            % Get linear vector prepared for for loop
            minimum = app.UniquenessMinEditField.Value;
            maximum = app.UniquenessMaxEditField.Value;
            step = app.UniquenessStepEditField.Value;

            testFWHM = minimum:step:maximum;
            BE_len = length(app.BE);

            %Restrict data to fitting range
            UniqueBE = app.BE(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);
            %             UniqueIntensity = app.BSintensity(app.BE<=app.maxFitBound & app.BE>=app.minFitBound);
            UniqueIntensity = app.BSintensity;

            UniquenessOutput = zeros(length(testFWHM),2);

            for FWHM = testFWHM
                index = index + 1;

                [~,resnorm] = fitting(app,UniqueBE,UniqueIntensity,FWHM,"Unique");
                MSE = resnorm/BE_len;
                UniquenessOutput(index,1) = FWHM;
                UniquenessOutput(index,2) = MSE;
            end

            MinMSE = min(UniquenessOutput(:,2));
            FWHMatMinMSE = UniquenessOutput(UniquenessOutput(:,2)==MinMSE,1);

            line(app.UniquenessAxes,UniquenessOutput(:,1),UniquenessOutput(:,2),'Marker','s');
            text(app.UniquenessAxes,mean(UniquenessOutput(:,1)),max(UniquenessOutput(:,2))*0.8,...
                ['Min @ ' num2str(FWHMatMinMSE) ' eV.'], "FontSize",24);

            app.ginputLabel.Visible = 'off';                % Hide message
            app.UniquenessAxes.Visible = 'on';              % Show plot if didn't run into errors
        end

        % Button pushed function: ApplyShiftButton
        function ApplyShiftButtonPushed(app, event)
            %% Apply any shifts needed.
            app.BE = app.BE + app.BEshiftEditField.Value;
            app.BEshift = app.BEshift + app.BEshiftEditField.Value; % Update BE shift tracker

            app.XPeakGuess = app.XPeakGuess + app.BEshiftEditField.Value;
            app.minFitBound = app.minFitBound + app.BEshiftEditField.Value;
            app.maxFitBound = app.maxFitBound + app.BEshiftEditField.Value;

            cla(app.BGAxes);
            cla(app.PeakPositionAxes);

            %% Plot data for BG and initial peak guess axis.
            line(app.BGAxes, app.BE, app.Intensity);
            app.BGAxes.Visible = 'on';

            % Delete and replot old fit bound lines if there
            if isempty(app.LowerFitBoundLine)
                ...
            else
                app.LowerFitBoundLine = xline(app.BGAxes,app.minFitBound);
                app.UpperFitBoundLine = xline(app.BGAxes,app.maxFitBound);
            end

            % Delete and replot old background line if there
            if isempty(app.InitialBackground)
                ...
            else
                if isempty(app.LinearBGslope)   % Shirley Background
                    bkg = app.Background;
                else                            % Linear Background
                    bkg = app.LinBackground;
                end

                app.InitialBackground = line(app.BGAxes,app.BE,bkg,'Color',[0.9290 0.6940 0.1250]);
            end

            % Delete and replot old initial guess points if there
            if isempty(app.InitialGuessPoints)
                app.PeakPositionAxes.Visible = 'off';
            else
                delete(app.InitialGuessPoints)
                app.PeakPositionAxes.Visible = 'on';
                line(app.PeakPositionAxes, app.BE, app.Intensity);

                app.InitialGuessPoints = line(app.PeakPositionAxes,app.XPeakGuess,app.YPeakGuess,"LineStyle","none","Marker",'o',"MarkerEdgeColor",'r',"MarkerFaceColor",'r');
            end

            %% Clear Uniqueness and Fit Result axes
            app.UniquenessAxes.Visible = 'off';
            app.FitResultAxes.Visible = 'off';

            delete(app.UniquenessAxes.Children);
            delete(app.FitResultAxes.Children);
        end

        % Button pushed function: SetAxesLimitsButton
        function SetAxesLimitsButtonPushed(app, event)
            app.FitResultAxes.XLim = [app.AxesTable.Data{1,1} app.AxesTable.Data{1,2}];
            app.FitResultAxes.YLim = [app.AxesTable.Data{2,1} app.AxesTable.Data{2,2}];
        end

        % Button pushed function: ShiftResidualsButton
        function ShiftResidualsButtonPushed(app, event)
            app.FitResidualsData.YData = app.FitResidualsData.YData + app.ShiftresidualsEditField.Value;
            app.FitResidualsRef.YData = app.FitResidualsRef.YData + app.ShiftresidualsEditField.Value;
        end

        % Button pushed function: ExportFitResultsButton
        function ExportFitResultsButtonPushed(app, event)
            app.ginputLabel.Text = 'Exporting...';
            app.ginputLabel.Visible = 'on';             % Show message
            pause(1E-5)                                 % Needed to update message

            % Export Fit Result plot
            ExportFilename = strrep(app.FullFileName,'.ascii','_FitPlot.emf');
            ExportFigName = strrep(app.FullFileName,'.ascii','_FitPlot.fig');

            exportgraphics(app.FitResultAxes,ExportFilename,'ContentType',"vector");

            % Create second figure and then save
            Fig = figure;
            FigAxes = axes(Fig);
            legend(FigAxes)
            children = app.FitResultAxes.Children;
            copyobj(children, FigAxes);

            % Transfer important properties
            FigAxes.FontSize = app.FitResultAxes.FontSize;
            FigAxes.Legend.String = app.FitResultAxes.Legend.String;
            FigAxes.Legend.Location = app.FitResultAxes.Legend.Location;
            FigAxes.Legend.Box = app.FitResultAxes.Legend.Box;
            FigAxes.Box = app.FitResultAxes.Box;
            FigAxes.FontWeight = app.FitResultAxes.FontWeight;
            FigAxes.XDir = app.FitResultAxes.XDir;
            FigAxes.LineWidth = app.FitResultAxes.LineWidth;
            FigAxes.DataAspectRatio = app.FitResultAxes.DataAspectRatio;
            FigAxes.DataAspectRatioMode = app.FitResultAxes.DataAspectRatioMode;
            FigAxes.XLabel.String = app.FitResultAxes.XLabel.String;
            FigAxes.YLabel.String = app.FitResultAxes.YLabel.String;
            FigAxes.Title.String = "";

            savefig(Fig, ExportFigName);
            delete(Fig);

            % Export Fit parameters as CSV from table
            FitBoundMin = app.minFitBound;
            FitBoundMax = app.maxFitBound;
            if isempty(app.LinearBGslope)
                BGtype = "Shirley";
                BGmin = app.LowShirleyBound;
                BGmax = app.HighShirleyBound;
            else
                BGtype = "Linear";
                BGmin = app.LowLinearBound;
                BGmax = app.HighLinearBound;
            end
            InitialGuessX = app.InitialGuessPoints.XData;
            InitialGuessY = app.InitialGuessPoints.YData;
            FWHM = app.FWHMEditField.Value;
            TotalBEshift = app.BEshift;

            ExportCellData = {FitBoundMin, FitBoundMax,BGtype,BGmin,BGmax,InitialGuessX,InitialGuessY,FWHM,TotalBEshift};
            ExportCellHeaders = {'Lower Fitting Bound (eV)','Upper Fitting Bound (eV)',...
                'Background type', 'Background X min (eV)', 'Background X max (eV)', ...
                'Guess Peaks X (eV)', 'Guess Peaks Y (Int)', 'FWHM (eV)','BE shift applied (eV)'};
            ExportCell = [ExportCellHeaders; ExportCellData];

            ExportCellFilename = strrep(app.FullFileName,'.ascii','_FitParams.csv');
            writecell(ExportCell, ExportCellFilename,'Delimiter',',');


            app.ginputLabel.Visible = 'off';             % Hide message
        end

        % Key press function: XPSFittingUIFigure
        function XPSFittingUIFigureKeyPress(app, event)
            if app.PeakBool == 1
                key = event.Key;

                if key == 'escape'
                    app.PeakBool = 0;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.XPSFittingUIFigure = uifigure('Visible', 'off');
            app.XPSFittingUIFigure.Name = 'XPS Fitting';
            app.XPSFittingUIFigure.Icon = fullfile(pathToMLAPP, 'XPS_Fitting_resources', 'logo.png');
            app.XPSFittingUIFigure.KeyPressFcn = createCallbackFcn(app, @XPSFittingUIFigureKeyPress, true);
            app.BGAxes = uiaxes(app.XPSFittingUIFigure);
            title(app.BGAxes, 'Background')
            xlabel(app.BGAxes, 'Binding Energy (eV)')
            ylabel(app.BGAxes, 'Intensity (a.u.)')
            zlabel(app.BGAxes, 'Z')
            app.BGAxes.XDir = 'reverse';
            app.PeakPositionAxes = uiaxes(app.XPSFittingUIFigure);
            title(app.PeakPositionAxes, 'Initial Peak Positions')
            xlabel(app.PeakPositionAxes, 'Binding Energy (eV)')
            ylabel(app.PeakPositionAxes, 'Intensity (a.u.)')
            zlabel(app.PeakPositionAxes, 'Z')
            app.PeakPositionAxes.XDir = 'reverse';
            app.FitResultAxes = uiaxes(app.XPSFittingUIFigure);
            title(app.FitResultAxes, 'Fit Results')
            xlabel(app.FitResultAxes, 'Binding Energy (eV)')
            ylabel(app.FitResultAxes, 'Intensity (a.u.)')
            zlabel(app.FitResultAxes, 'Z')
            app.FitResultAxes.XDir = 'reverse';
            app.UniquenessAxes = uiaxes(app.XPSFittingUIFigure);
            title(app.UniquenessAxes, 'Uniqueness Plot')
            xlabel(app.UniquenessAxes, 'FWHM (eV)')
            ylabel(app.UniquenessAxes, 'Mean Squared Error')
            zlabel(app.UniquenessAxes, 'Z')
            app.UniquenessAxes.YScale = 'log';
            app.ginputLabel = uilabel(app.XPSFittingUIFigure);
            app.ginputLabel.Visible = 'off';
            app.ginputLabel.Text = 'You are now selecting ***';
            app.ExportFitResultsButton = uibutton(app.XPSFittingUIFigure, 'push');
            app.ExportFitResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFitResultsButtonPushed, true);
            app.ExportFitResultsButton.Enable = 'off';
            app.ExportFitResultsButton.Text = 'Export Fit Results';
            app.ShiftResidualsButton = uibutton(app.XPSFittingUIFigure, 'push');
            app.ShiftResidualsButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftResidualsButtonPushed, true);
            app.ShiftResidualsButton.Text = 'Shift';
            app.LinslopeminEditFieldLabel = uilabel(app.XPSFittingUIFigure);
            app.LinslopeminEditFieldLabel.Text = 'Lin. slope min:';
            app.LinslopeminEditField = uieditfield(app.XPSFittingUIFigure, 'numeric');
            app.LinslopeminEditField.Value = -Inf;
            app.maxEditFieldLabel = uilabel(app.XPSFittingUIFigure);
            app.maxEditFieldLabel.Text = 'max:';
            app.LinslopemaxEditField = uieditfield(app.XPSFittingUIFigure, 'numeric');
            app.LinslopemaxEditField.Value = Inf;
            app.ToleranceoninitiallinBGEditFieldLabel = uilabel(app.XPSFittingUIFigure);
            app.ToleranceoninitiallinBGEditFieldLabel.Text = 'Tolerance on initial lin. BG (%)';
            app.LinBGToleranceEditField = uieditfield(app.XPSFittingUIFigure, 'numeric');
            app.LinBGToleranceEditField.Value = 10;
            app.ShiftresidualsEditFieldLabel = uilabel(app.XPSFittingUIFigure);
            app.ShiftresidualsEditFieldLabel.Text = 'Shift residuals';
            app.ShiftresidualsEditField = uieditfield(app.XPSFittingUIFigure, 'numeric');
            app.ShiftresidualsEditField.Value = 100;
            app.ButtonPanel = uipanel(app.XPSFittingUIFigure);
            app.GridLayout = uigridlayout(app.ButtonPanel);
            app.FitRangeGrid = uigridlayout(app.GridLayout);
            app.SetFitRangeMouseButton = uibutton(app.FitRangeGrid, 'push');
            app.SetFitRangeMouseButton.ButtonPushedFcn = createCallbackFcn(app, @SetFitRangeMouseButtonPushed, true);
            app.SetFitRangeMouseButton.Enable = 'off';
            app.SetFitRangeMouseButton.Text = 'Set Fit Range (Mouse)';
            app.SetFitRangeKBDButton = uibutton(app.FitRangeGrid, 'push');
            app.SetFitRangeKBDButton.ButtonPushedFcn = createCallbackFcn(app, @SetFitRangeKBDButtonPushed, true);
            app.SetFitRangeKBDButton.Enable = 'off';
            app.SetFitRangeKBDButton.Text = 'Set Fit Range (KBD)';
            app.BkgGrid = uigridlayout(app.GridLayout);
            app.ShirleyBGButton = uibutton(app.BkgGrid, 'push');
            app.ShirleyBGButton.ButtonPushedFcn = createCallbackFcn(app, @ShirleyBGButtonPushed, true);
            app.ShirleyBGButton.Enable = 'off';
            app.ShirleyBGButton.Text = 'Set Shirley BG';
            app.LinearBGButton = uibutton(app.BkgGrid, 'push');
            app.LinearBGButton.ButtonPushedFcn = createCallbackFcn(app, @LinearBGButtonPushed, true);
            app.LinearBGButton.Enable = 'off';
            app.LinearBGButton.Text = 'Set Linear BG';
            app.SelectInitialPeaksButton = uibutton(app.GridLayout, 'push');
            app.SelectInitialPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInitialPeaksButtonPushed, true);
            app.SelectInitialPeaksButton.Enable = 'off';
            app.SelectInitialPeaksButton.Text = 'Select Initial Peaks';
            app.PerformFitButton = uibutton(app.GridLayout, 'push');
            app.PerformFitButton.ButtonPushedFcn = createCallbackFcn(app, @PerformFitButtonPushed, true);
            app.PerformFitButton.Enable = 'off';
            app.PerformFitButton.Text = 'Perform Fit';
            app.FitReuseButton = uibutton(app.GridLayout, 'push');
            app.FitReuseButton.ButtonPushedFcn = createCallbackFcn(app, @FitReuseButtonPushed, true);
            app.FitReuseButton.Enable = 'off';
            app.FitReuseButton.Text = 'Fit Using Previous Settings';
            app.LoadDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ConfigPanel = uipanel(app.XPSFittingUIFigure);
            app.GridLayout4 = uigridlayout(app.ConfigPanel);
            app.BEshifteVEditFieldLabel = uilabel(app.GridLayout4);
            app.BEshifteVEditFieldLabel.Text = 'BE shift (eV)';
            app.BEshiftEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FWHMeVLabel = uilabel(app.GridLayout4);
            app.FWHMeVLabel.Text = 'FWHM ± X% (eV)';
            app.FWHMEditField = uieditfield(app.GridLayout4, 'numeric');
            app.FWHMEditField.Value = 1.5;
            app.ApplyShiftButton = uibutton(app.GridLayout4, 'push');
            app.ApplyShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyShiftButtonPushed, true);
            app.ApplyShiftButton.Enable = 'off';
            app.ApplyShiftButton.Text = 'Apply Shift';
            app.Guaussianmix0100GaussianLabel = uilabel(app.GridLayout4);
            app.Guaussianmix0100GaussianLabel.Text = {'Guaussian mix'; '(0 = 100% Gaussian)'};
            app.GaussianMixEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GaussianMixEditField.Value = 0.1;
            app.UniquenessPlotButton = uibutton(app.GridLayout4, 'push');
            app.UniquenessPlotButton.ButtonPushedFcn = createCallbackFcn(app, @UniquenessPlotButtonPushed, true);
            app.UniquenessPlotButton.Enable = 'off';
            app.UniquenessPlotButton.Text = 'Generate Uniqueness Plot of FWHM';
            app.MineVEditFieldLabel = uilabel(app.GridLayout4);
            app.MineVEditFieldLabel.Text = 'Min (eV)';
            app.UniquenessMinEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UniquenessMinEditField.Value = 1;
            app.MaxeVEditFieldLabel = uilabel(app.GridLayout4);
            app.MaxeVEditFieldLabel.Text = 'Max (eV)';
            app.UniquenessMaxEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UniquenessMaxEditField.Value = 2;
            app.StepeVEditFieldLabel = uilabel(app.GridLayout4);
            app.StepeVEditFieldLabel.Text = 'Step (eV)';
            app.UniquenessStepEditField = uieditfield(app.GridLayout4, 'numeric');
            app.UniquenessStepEditField.Value = 0.01;
            app.FitResultsAxesLimitsLabel = uilabel(app.GridLayout4);
            app.FitResultsAxesLimitsLabel.Text = 'Fit Results Axes Limits';
            app.AxesTable = uitable(app.GridLayout4);
            app.AxesTable.ColumnName = {'Min'; 'Max'};
            app.AxesTable.RowName = {'Xaxis;Yaxis'};
            app.AxesTable.ColumnEditable = true;
            app.SetAxesLimitsButton = uibutton(app.GridLayout4, 'push');
            app.SetAxesLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SetAxesLimitsButtonPushed, true);
            app.SetAxesLimitsButton.Text = 'Set Axes Limits';
            app.FileloadedLabel = uilabel(app.GridLayout4);
            app.FileloadedLabel.Text = 'File loaded:';
            app.FileloadedEditField = uieditfield(app.GridLayout4, 'text');
            app.Percent = uieditfield(app.GridLayout4, 'numeric');
            app.Percent.Value = 10;
            % Show the figure after all components are created
            app.XPSFittingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarSpecXPSapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.XPSFittingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.XPSFittingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.XPSFittingUIFigure)
        end
    end
end